classdef IPPR_GroupAssignment_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ResultLabel         matlab.ui.control.Label
        TypeEditField       matlab.ui.control.EditField
        TypeofInputCurrency5102050100EditFieldLabel  matlab.ui.control.Label
        CheckButton         matlab.ui.control.Button
        SelectanImageLabel  matlab.ui.control.Label
        ImportButton        matlab.ui.control.Button
        MalaysianRinggitFakeCurrencyDetectionSystemLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Filepath = '';
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, resultDisplay)
            % app.ResultLabel.Text = resultDisplay;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed2(app, event)
            % Open a file dialog to select an image
            [file, path, indx] = uigetfile({'*.jpg;*.png;*.bmp;*.tif;*.jpeg', 'Image Files'; '*.*', 'All Files'}, 'Select Image File');
            if isequal(file, 0)
                % User canceled the file dialog
                return;
            end
    
            % Create the full file path
            app.Filepath = fullfile(path, file);
    
            % Display the selected image in the axes component 
            imshow(app.Filepath, 'Parent', app.UIAxes);
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            % Get user input from the TypeEditField
            type = app.TypeEditField.Value;
            assignin("base", 'RMtype', type)
            assignin("base", 'imageFilePath', app.Filepath)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.MalaysianRinggitFakeCurrencyDetectionSystemLabel = uilabel(app.UIFigure);
            app.MalaysianRinggitFakeCurrencyDetectionSystemLabel.Text = 'Malaysian Ringgit Fake Currency Detection System';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed2, true);
            app.ImportButton.Text = 'Import';
            app.SelectanImageLabel = uilabel(app.UIFigure);
            app.SelectanImageLabel.Text = 'Select an Image:';
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.TypeofInputCurrency5102050100EditFieldLabel = uilabel(app.UIFigure);
            app.TypeofInputCurrency5102050100EditFieldLabel.Text = {'Type of Input Currency:'; '(5/10/20/50/100)'};
            app.TypeEditField = uieditfield(app.UIFigure, 'text');
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IPPR_GroupAssignment_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end